classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        InitialLabel           matlab.ui.control.Label
        YFeatureLabel_2        matlab.ui.control.Label
        XFeatureLabel_2        matlab.ui.control.Label
        PauseButton            matlab.ui.control.Button
        CompleteLabel_2        matlab.ui.control.Label
        CompleteLabel          matlab.ui.control.Label
        CellInfoLabel          matlab.ui.control.Label
        PlotFeaturesText       matlab.ui.control.Label
        NextButton             matlab.ui.control.Button
        PreviousButton         matlab.ui.control.Button
        ModeSwitch             matlab.ui.control.Switch
        ImageDisp              matlab.ui.control.Image
        ExportData_Button      matlab.ui.control.Button
        Credit                 matlab.ui.control.Hyperlink
        XDropDown              matlab.ui.control.DropDown
        YDropDown              matlab.ui.control.DropDown
        Number_of_Cells_Field  matlab.ui.control.NumericEditField
        Number_of_Cells_Text   matlab.ui.control.Label
        Segment_Button         matlab.ui.control.Button
        Help_Button            matlab.ui.control.Button
        Settings_Button        matlab.ui.control.Button
        UploadImagesButton     matlab.ui.control.Button
        ProgressBarAxes        matlab.ui.control.UIAxes
        HistogramAxes          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Images cell = {}; % Stores the uploaded image
    CurrentImageIndex double = 1;
    SegmentedImages = []; % Stores the segmented image
    net = []; % Stores the pre-trained CNN network
    Masks cell = {};
    Boxes cell = {};
    Labels cell = {};
    Scores cell = {};
    AnalysisData = [];
        Progress = 0; % Description
    end
    
    methods (Access = private)
        
        function [] = fixIMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly
            %title(ImageAxes, []);
            %xlabel(ImageAxes, []);
            %ylabel(ImageAxes, []);
            %ImageAxes.XAxis.TickLabels = {};
            %ImageAxes.YAxis.TickLabels = {};
            %ImageAxes.PositionConstraint="outerposition";
            %ImageAxes.InnerPosition=ImageAxes.OuterPosition
    %         I = imshow(Image, 'Parent', app.ImageAxes, ...
    % 'XData', [1 app.ImageAxes.Position(3)], ...
    % 'YData', [1 app.ImageAxes.Position(4)]);
    %         box(ImageAxes,"on")
        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end
       
        end
       
        function updateDisplayedImage(app)
            if isempty(app.SegmentedImages)
                return;
            end
    
            fixIMGDisplay(app, app.SegmentedImages{app.CurrentImageIndex}, app.ImageDisp);
            data = app.AnalysisData{app.CurrentImageIndex};
            app.Number_of_Cells_Field.Value = size(data, 1);
            app.Area_Field.Value = mean(data.Area);
            app.updatePlot();
        end

       
        function updatePlot(app)
       % Plotting depending on toggles
            if isempty(app.statsPerImage)
                return;
            end

            % Gather stats
            if  strcmp(app.ToggleCumulative.Value, 'On')
                % Cumulative across all images
                combinedStats = vertcat(app.statsPerImage{:});
            else
                % Just show first image's stats
                combinedStats = app.statsPerImage{1};
            end

            % Decide plot type
            if  strcmp(app.YDropDown.Value, 'Frequency')
                % Histogram view
                featureNameX = app.XDropDown.Value;
                featureValues = [combinedStats.(featureName)];
                histogram(app.HistogramAxes, featureValues);
            else
                % Scatter plot view
                featureX = app.XDropDown.Value;
                featureY = app.YDropDown.Value;

                xValues = [combinedStats.(featureX)];
                yValues = [combinedStats.(featureY)];

                scatter(app.HistogramAxes, xValues, yValues, 'filled');
            end
                xlabel(app.HistogramAxes, featureX);
                ylabel(app.HistogramAxes, featureY);
        end

      
        function UpdateProgress(app)
            barh(app.Progress, 'Parent',app.ProgressBarAxes,'EdgeColor','none','FaceColor',[0.4660 0.6740 0.1880], "BarWidth" ,20000)
            drawnow;
        end
    

    function hoverCellInfo(app)
        try
            % Get mouse position over the axes
            mousePos = get(app.ImageDisp, 'CurrentPoint');
            x = mousePos(1,1);
            y = mousePos(1,2);

            boxes = app.Boxes;
            found = false;

            % Delete previous highlight if exists
            if isfield(app, 'HighlightRect') && isvalid(app.HighlightRect)
                delete(app.HighlightRect);
            end

            for i = 1:size(boxes,1)
                box = boxes(i,:);
                if x >= box(1) && x <= box(1)+box(3) && ...
                   y >= box(2) && y <= box(2)+box(4)

                    cellData = app.AnalysisData{1}(i,:);
                    infoStr = sprintf(['Cell %d:\nArea: %.2f\nPerimeter: %.2f\n' ...
                                      'Eccentricity: %.2f\nSolidity: %.2f'], ...
                                      i, cellData.Area, cellData.Perimeter, ...
                                      cellData.Eccentricity, cellData.Solidity);
                    app.CellInfoLabel.Text = infoStr;

                    hold(app.ImageDisp, 'on');
                    app.HighlightRect = rectangle(app.ImageDisp, ...
                        'Position', box, ...
                        'EdgeColor', 'r', 'LineWidth', 1.5, 'LineStyle', '--');
                    hold(app.ImageDisp, 'off');

                    found = true;
                    break;
                end
            end

            if ~found
                app.CellInfoLabel.Text = 'Hover over a cell to view info.';
            end
        catch
            app.CellInfoLabel.Text = 'Error reading hover data.';
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixIMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
            addpath(genpath('MRCNNsrc'));

            app.net = load('net'); % Load the network file
            app.net = app.net.net; % Extract the network object
            set(app.ProgressBarAxes,'visible','off');
            set(app.ProgressBarAxes,'xtick',[]);
            UpdateProgress(app);
            set(app.ProgressBarAxes, 'XLim', [0 1]);

        end

        % Callback function: ImageDisp, UploadImagesButton
        function UploadImage(app, event)
            app.InitialLabel.Visible = 'off';
            
            [files, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select Images', 'MultiSelect', 'on');
            if isequal(files, 0)
                errordlg('No images selected!', 'Error');
                return;
            end

            if ~iscell(files)
                files = {files};
            end

            app.Images = {};
            for k = 1:length(files)
                img = rescale(imread(fullfile(path, files{k})));
                [img, ~] = resizeImageandMask(img, [], [528, 704]);

                if size(img, 3) == 3
                    img = mean(img, 3);
                end
                app.Images{end+1} = img;
            end

            app.CurrentImageIndex = 1;
            app.SegmentedImages = {};
            app.Masks = {};
            app.Boxes = {};
            app.Labels = {};
            app.Scores = {};
            app.AnalysisData = {};
            fixIMGDisplay(app, app.Images{1}, app.ImageDisp);
         
        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
        if isempty(app.Images)
        errordlg('Please upload an image first!', 'Error');
        return;
        end 


        app.HistogramAxes.Visible = 'on';
        app.Number_of_Cells_Field.Visible = 'on';
        app.Number_of_Cells_Text.Visible = 'on';
        app.PlotFeaturesText.Visible = 'on';
        app.ModeSwitch.Visible = 'on';
        app.NextButton.Visible = 'on';
        app.PreviousButton.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.PauseButton.Visible = 'on';
        app.XFeatureLabel_2.Visible = 'on';
        app.YFeatureLabel_2.Visible = 'on';

        
    % Add MRCNNsrc path (Make sure this path is correct)

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end


    % Perform cell segmentation
    try
        if ~class(app.Images)=='cell'
            app.Images{1} = app.Images; %makes sure all images have same format: cell, containing each image
            % move this logic to images being loaded later 
        end

        for i=[1:size(app.Images,2)] %for each image loaded in
        
         app.Progress = ((i-1)+ 0.05) / size(app.Images,2);
         UpdateProgress(app);
        
        [app.Masks{i} app.Labels{i} app.Scores{i} app.Boxes{i}] = segmentCells(app.net, rescale(app.Images{i}), "NumstrongestRegions",1000, MinSize=[4 4], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=0.5);

         app.Progress = ((i-1)+ 0.9) / size(app.Images,2);
         UpdateProgress(app);
         app.CompleteLabel.Visible = 'on';
            
        if isempty(app.Masks{i})
            app.SegmentedImages = app.Images{i};
        else
            app.SegmentedImages = insertObjectMask(app.Images{i}, app.Masks{i}, Color=lines(size(app.Masks{i}, 3)) );
        end
        % Display the segmented image with masks
        fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
        drawnow
        app.AnalysisData{i} = ExtractFeatures(app.Images{i}, app.Masks{i}, app.Boxes{i}, app.Scores{i}, 1);
        app.CompleteLabel.Visible = 'off';
        app.CompleteLabel_2.Visible = 'on';
        
       % app.AnalysisData{1}.Area

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData{i}, 1);
        %app.Area_Field.Value = mean(app.AnalysisData.Area);
        app.Progress = i / size(app.Images,2);
        UpdateProgress(app);


        end
        app.PauseButton.Visible = 'off';
        
app.CellInfoLabel.Visible = 'on';

app.UIFigure.WindowButtonMotionFcn = @(src, event) hoverCellInfo(app);


    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end

        
        end

        % Value changed function: XDropDown, YDropDown
        function PlotHistogram(app, event)
            xFeature = app.XDropDown.Value;
            yFeature = app.YDropDown.Value;

            if isempty(app.AnalysisData)
                return;
            end

            % Extract the selected feature's values
            featureData = app.AnalysisData{1};

            % Decide plot type
            if  strcmp(yFeature, 'Frequency')
                
                % Plot histogram
                histogram(app.HistogramAxes,featureData.(xFeature));
                xlabel(app.HistogramAxes, xFeature);
                ylabel(app.HistogramAxes, 'Frequency');
            else
                % Scatter plot view
                xValues = featureData.(xFeature);
                yValues = featureData.(yFeature);

        % Create scatter plot
        scatter(app.HistogramAxes, xValues, yValues, 'filled');
        xlabel(app.HistogramAxes, xFeature);
        ylabel(app.HistogramAxes, yFeature);


            end
        end

        % Callback function
        function PreviousButtonPushed(app, event)
              if app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex - 1;
                app.updateDisplayedImage();
              end
        
        end

        % Callback function
        function NextButtonPushed(app, event)
           if app.CurrentImageIndex < length(app.SegmentedImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
                app.updateDisplayedImage();
           end
        
        end

        % Button pushed function: PreviousButton
        function ToggleSwitchValueChanged(app, event)

        end

        % Callback function
        function XAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end

        % Callback function
        function YAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            ylabel(app.HistogramAxes, 'Frequency')
            app.HistogramAxes.Visible = 'off';
            colormap(app.HistogramAxes, 'turbo')
            app.ProgressBarAxes = uiaxes(app.UIFigure);
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImagesButton.Text = 'Select Images';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.Icon = fullfile(pathToMLAPP, 'download.png');
            app.Settings_Button.Text = '';
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Visible = 'off';
            app.Number_of_Cells_Text.Text = 'Number of Cells ';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.Number_of_Cells_Field.Limits = [0 Inf];
            app.Number_of_Cells_Field.RoundFractionalValues = 'on';
            app.Number_of_Cells_Field.AllowEmpty = 'on';
            app.Number_of_Cells_Field.Editable = 'off';
            app.Number_of_Cells_Field.Visible = 'off';
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'Frequency', 'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.YDropDown.Visible = 'off';
            app.YDropDown.Value = 'Area';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.XDropDown.Visible = 'off';
            app.XDropDown.Value = 'Area';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/4th-Year-Image-Processing-Project';
            app.Credit.Text = {'GitHub'; ''};
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.Icon = fullfile(pathToMLAPP, 'export-icon-2048x2048-g2mr2v94.png');
            app.ExportData_Button.Text = '';
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'All', 'Individual'};
            app.ModeSwitch.Visible = 'off';
            app.ModeSwitch.Value = 'All';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged, true);
            app.PreviousButton.Icon = fullfile(pathToMLAPP, 'Picture 2.png');
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Icon = fullfile(pathToMLAPP, 'Picture 1.png');
            app.NextButton.Visible = 'off';
            app.NextButton.Text = '';
            app.PlotFeaturesText = uilabel(app.UIFigure);
            app.PlotFeaturesText.Visible = 'off';
            app.PlotFeaturesText.Text = 'Plot Features:';
            app.CellInfoLabel = uilabel(app.UIFigure);
            app.CellInfoLabel.Visible = 'off';
            app.CellInfoLabel.Text = 'Hover over cell to view info. ';
            app.CompleteLabel = uilabel(app.UIFigure);
            app.CompleteLabel.Visible = 'off';
            app.CompleteLabel.Text = '50% Complete';
            app.CompleteLabel_2 = uilabel(app.UIFigure);
            app.CompleteLabel_2.Visible = 'off';
            app.CompleteLabel_2.Text = '100% Complete';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'Pause.svg');
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = '';
            app.XFeatureLabel_2 = uilabel(app.UIFigure);
            app.XFeatureLabel_2.Visible = 'off';
            app.XFeatureLabel_2.Text = 'X ';
            app.YFeatureLabel_2 = uilabel(app.UIFigure);
            app.YFeatureLabel_2.Visible = 'off';
            app.YFeatureLabel_2.Text = 'Y';
            app.InitialLabel = uilabel(app.UIFigure);
            app.InitialLabel.Text = 'Please select images to start.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end